classdef farfood < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UITable                     matlab.ui.control.Table
        BuscarButton                matlab.ui.control.Button
        FarFoodv1InteraccionesentrefarmacosyalimentosLabel  matlab.ui.control.Label
        BusquedaDropDownLabel       matlab.ui.control.Label
        BusquedaDropDown            matlab.ui.control.DropDown
        TerminodeBusquedaEditFieldLabel  matlab.ui.control.Label
        TerminodeBusquedaEditField  matlab.ui.control.EditField
        UITable2                    matlab.ui.control.Table
    end

    
    properties (Access = public)
        mydata % datos salvados
    end
    

    methods (Access = private)

        % Button pushed function: BuscarButton
        function BuscarButtonPushed(app, event)
           app.mydata = load('data_tesis8.mat');
           keyword=app.TerminodeBusquedaEditField.Value;
           res=app.mydata.ressdf5(find(strcmpi(app.mydata.ressdf5.drug_name,keyword)),:);
           app.UITable.Data = res;
           

        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices
            id_comp=table2array(app.UITable.Data(indices(1),indices(2)));
            
            
            res2=app.mydata.Content_app(find(app.mydata.Content_app.orig_source_name==id_comp),:);
            %res2=id_comp;
            app.UITable2.Data=res2;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Comp ID'; 'Comp Name'; 'Drug ID'; 'Tanimoto index'; 'Drug Name'; 'Ind_2_drug'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.BuscarButton = uibutton(app.UIFigure, 'push');
            app.BuscarButton.ButtonPushedFcn = createCallbackFcn(app, @BuscarButtonPushed, true);
            app.BuscarButton.Text = 'Buscar';
            app.FarFoodv1InteraccionesentrefarmacosyalimentosLabel = uilabel(app.UIFigure);
            app.FarFoodv1InteraccionesentrefarmacosyalimentosLabel.Text = 'FarFood v.1. Interacciones entre farmacos y alimentos';
            app.BusquedaDropDownLabel = uilabel(app.UIFigure);
            app.BusquedaDropDownLabel.Text = 'Busqueda';
            app.BusquedaDropDown = uidropdown(app.UIFigure);
            app.BusquedaDropDown.Items = {'Drug name', 'Drug ID'};
            app.BusquedaDropDown.Value = 'Drug name';
            app.TerminodeBusquedaEditFieldLabel = uilabel(app.UIFigure);
            app.TerminodeBusquedaEditFieldLabel.Text = 'Termino de Busqueda';
            app.TerminodeBusquedaEditField = uieditfield(app.UIFigure, 'text');
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = farfood

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end